classdef MINFLUX_data_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MINFLUXDataViewerUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenrawdatamatMenu         matlab.ui.container.Menu
        OpenrecentfilesMenu        matlab.ui.container.Menu
        SaverawdatamatMenu         matlab.ui.container.Menu
        OpenconfocalimageMenu      matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        DataSelectorMenu           matlab.ui.container.Menu
        FilterMenu                 matlab.ui.container.Menu
        ROIMenu                    matlab.ui.container.Menu
        PreferenceMenu             matlab.ui.container.Menu
        ViewerMenu                 matlab.ui.container.Menu
        AttributePlotMenu          matlab.ui.container.Menu
        HistogramMenu              matlab.ui.container.Menu
        ScatterPlotMenu            matlab.ui.container.Menu
        RenderImageMenu            matlab.ui.container.Menu
        StandardRenderMenu         matlab.ui.container.Menu
        InteractiveRenderMenu      matlab.ui.container.Menu
        TraceViewMenu              matlab.ui.container.Menu
        ProcessMenu                matlab.ui.container.Menu
        AnalysisMenu               matlab.ui.container.Menu
        LocalizationPrecisionMenu  matlab.ui.container.Menu
        RIMFZscalefactorMenu       matlab.ui.container.Menu
        LocalDensityMenu           matlab.ui.container.Menu
        PluginsMenu                matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        MonitorMemoryMenu          matlab.ui.container.Menu
        debugMenu                  matlab.ui.container.Menu
        apptoMATLABbaseMenu        matlab.ui.container.Menu
        removeallpreferencesMenu   matlab.ui.container.Menu
        Toolbar                    matlab.ui.container.Toolbar
        ROI_box                    matlab.ui.container.toolbar.PushTool
        ROI_oval                   matlab.ui.container.toolbar.PushTool
        ROI_polygon                matlab.ui.container.toolbar.PushTool
        ROI_freehand               matlab.ui.container.toolbar.PushTool
        ROI_line                   matlab.ui.container.toolbar.PushTool
        ROI_crosshair              matlab.ui.container.toolbar.PushTool
        LUT                        matlab.ui.container.toolbar.PushTool
        Color                      matlab.ui.container.toolbar.PushTool
        StatusTextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Prefs = struct;
        data = {};          % {file, prop, attr, calibration, channel, active}
        filters = {};       % {apply, attr, plotVar, minVal, maxVal, (ftr)}
        children_apps = {}; % {fig_histogram, fig_attribute..., dialog_Prefs, dialog_LUT..}
    end

    properties (Access = private)
        % 
        % Colormaps = {
        %     "default", "hot", "autumn", "summer", "gray", "sky", "abyss", ...
        %     "jet", "parula", "turbo", "glasbey", "hsv", "bone", "copper", ...
        %     "pink", "flag", "lines", "colorcube", "prism", "cool", "spring", ...
        %     "winter", "white" };

    
        % Declare properties of the PatientsDisplay class.
        %file            % MINFLUX file name
        %path            % MINFLUX file path (dir)
        
        % firstLoad       % boolean, true when 1st dataset loaded
        % %data            % MINFLUX raw data loaded as struct
        % attributes      % MINFLUX data attributes
        % nItr            % number of iteration
        % nLoc            % number of recorded localization
        % traceID         % unique trace IDs
        % trace_idx       % begin and end index of each trace in loc
        % traceLength     % number of localizations per trace
        % 
        % %data_filtered  % MINFLUX raw data after applied filter(s)
        % %FilterDialogApp % Filter Dialog app
        % ftr
        % filters         % cell array to store filters
        % filter_idx_selected
        % %app.ftr             % N by 1 logical array, serve as filter
        % 
        % TraceViewerApp
        % 
        % xyz             % raw x,y,z coordiates of localization data
        % nDim            % localization data dimension, 0 when no valid data detected
        % zScale          % Z aixs scaling factor, as realZ = dataZ * zScale
        % 
        % %attr_1
        % %attr_2
        % val_1
        % val_2
        % val_c
        % linePlot
        % 
        % histPlot
        % val_hist
        % hist_minLine
        % hist_maxLine
        % 
        % 
        % %mainPlotColor
        % 
        % %roi_exist           % boolean to decide if there's ROI in plots
        % roi
        % index_roi           % index of data within ROI
        % linePlot_roi        % line object
        % histPlot_roi        % histogram object
        % histPlot_roi_box    % rectangle object
        % scatterPlot_roi     % scatter object
        % 
        % color_roi
        % 
        % 
        % frontView
        % fig_scatter
        % ax_scatter
        % scatterPlot
        
        %preference_dialog_app % Description
        %filter_dialog_app
        %histogram_dialog_app


    end
    
    methods (Access = private)

        function populate_recent_file_menu (app)
            if ~isprop(app, "Prefs")
                load_Prefs (app);
            end
            if (isfield(app.Prefs.file, "recent_files") ...
                    && ~isempty(app.Prefs.file.recent_files) ...
                    && app.Prefs.file.num_file_history ~= 0)
                recent_files = app.Prefs.file.recent_files;
                num_entry = min(length(recent_files), app.Prefs.file.num_file_history);
                for i = 1 : num_entry
                    uimenu(app.OpenrecentfilesMenu, 'Text', recent_files{i}, 'MenuSelectedFcn', {@open_recent_file, app});
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % add drag and drop functionality to UI
            enable_drag_and_drop (app.MINFLUXDataViewerUIFigure, @file_dropped);
            % call-back function to handel drag and drop event
            function file_dropped (~, evt)
                for i = 1 : length(evt.names)
                    parse_file_path ( app, evt.names{i} );
                end
            end

            % load Preferences
            load_Prefs (app);
            
            % populate recent files menu entry
            populate_recent_file_menu (app)

        end

        % Close request function: MINFLUXDataViewerUIFigure
        function MINFLUXDataViewerUIFigureCloseRequest(app, event)
            % if isgraphics(app.fig_scatter)
            %     delete(app.fig_scatter);
            % end
            for i = length ( app.children_apps ) : -1 : 1
                if ~isempty( app.children_apps{i} )
                    delete( app.children_apps{i} );
                end
            end
            delete(app);         
        end

        % Menu selected function: OpenrawdatamatMenu
        function OpenrawdatamatMenuSelected(app, event)
            if isfield(app.Prefs.file, "default_folder")
                [file, folder] = uigetfile('*.mat', "select MINFLUX raw data .mat file", app.Prefs.file.default_folder);
            else
                [file, folder] = uigetfile('*.mat', "select MINFLUX raw data .mat file");
            end
            load_data (app, file, folder);
        end

        % Menu selected function: OpenconfocalimageMenu
        function OpenconfocalimageMenuSelected(app, event)
            if isfield(app.Prefs.file, "default_folder")
                [file, folder] = uigetfile({'*.tif';'*.tiff'}, "select confocal tif image ", app.Prefs.file.default_folder);
            else
                [file, folder] = uigetfile({'*.tif';'*.tiff'}, "select confocal tif image ");
            end
            load_tif (app, file, folder);
        end

        % Menu selected function: SaverawdatamatMenu
        function SaverawdatamatMenuSelected(app, event)
            save_data (app);
        end

        % Callback function: ROI_box
        function ROI_boxClicked(app, event)
            app.data.ROI.roi = drawrectangle (app.UIAxes, 'Color', app.color_roi, 'StripeColor', 'none', 'LineWidth', 1);
        end

        % Callback function: ROI_oval
        function ROI_ovalClicked(app, event)
            app.data.ROI.roi = drawellipse (app.UIAxes, 'Color', app.color_roi, 'StripeColor', 'none', 'LineWidth', 1);
        end

        % Callback function: ROI_polygon
        function ROI_polygonClicked(app, event)
            app.data.ROI.roi = drawpolygon (app.UIAxes, 'Color', app.color_roi, 'StripeColor', 'none', 'LineWidth', 1);
        end

        % Callback function: ROI_freehand
        function ROI_freehandClicked(app, event)
            app.data.ROI.roi = drawfreehand (app.UIAxes, 'Color', app.color_roi, 'StripeColor', 'none', 'LineWidth', 1);
        end

        % Callback function: ROI_line
        function ROI_lineClicked(app, event)
            app.data.ROI.roi = drawline (app.UIAxes, 'Color', app.color_roi, 'StripeColor', 'none', 'LineWidth', 1);
        end

        % Callback function: ROI_crosshair
        function ROI_crosshairClicked(app, event)
            app.data.ROI.roi = drawcrosshair (app.UIAxes, 'Color', app.color_roi, 'StripeColor', 'none', 'LineWidth', 1);
        end

        % Menu selected function: DataSelectorMenu
        function DataSelectorMenuSelected(app, event)
            app.children_apps{end+1} = dialog_info (app);
        end

        % Callback function: Color
        function ColorClicked(app, event)
            
            app.data.ROI.color_roi = uisetcolor();
            disp("color choosed: " + string(app.data.ROI.color_roi));

            % props = properties(MINFLUX_data_viewer);
            % for i = 1 : length(props)
            %     disp("property " + i + " : ");
            %     %disp(props{i}.Name);
            %     disp("class: " +  class( props(i).Name ) ); 
            % end


        end

        % Callback function: LUT
        function LUTClicked(app, event)
            app.children_apps{end+1} = dialog_LUT (app);
        end

        % Menu selected function: PreferenceMenu
        function PreferenceMenuSelected(app, event)
            app.children_apps{end+1} = dialog_preference (app);
        end

        % Menu selected function: FilterMenu
        function FilterMenuSelected(app, event)
            app.children_apps{end+1} = dialog_filter (app);
        end

        % Menu selected function: ROIMenu
        function ROIMenuSelected(app, event)
            
        end

        % Menu selected function: HistogramMenu
        function HistogramMenuSelected(app, event)
            app.children_apps{end+1} = plot_histogram (app);
        end

        % Menu selected function: RenderImageMenu
        function RenderImageMenuSelected(app, event)
            %app.children_apps{end+1} = render_localization (app);
        end

        % Menu selected function: StandardRenderMenu
        function StandardRenderMenuSelected(app, event)
            app.children_apps{end+1} = render_test (app);
        end

        % Menu selected function: InteractiveRenderMenu
        function InteractiveRenderMenuSelected(app, event)
            app.children_apps{end+1} = interactive_render_MINFLUX (app);
        end

        % Menu selected function: ScatterPlotMenu
        function ScatterPlotMenuSelected(app, event)
            app.children_apps{end+1} = plot_scatter (app);
        end

        % Menu selected function: AttributePlotMenu
        function AttributePlotMenuSelected(app, event)
            app.children_apps{end+1} = plot_attribute (app);
        end

        % Menu selected function: TraceViewMenu
        function TraceViewMenuSelected(app, event)
            
        end

        % Menu selected function: LocalizationPrecisionMenu
        function LocalizationPrecisionMenuSelected(app, event)
            idx = get_active_data_index (app);
            if isempty(idx)
                return;
            end
            app.data{idx}.calibration.loc_precision = compute_Loc_precision(app.data{idx}, 20, true);
        end

        % Menu selected function: RIMFZscalefactorMenu
        function RIMFZscalefactorMenuSelected(app, event)
            disp("compute RIMF callback");
        end

        % Menu selected function: LocalDensityMenu
        function LocalDensityMenuSelected(app, event)
            disp("compute local density callback");
        end

        % Menu selected function: MonitorMemoryMenu
        function MonitorMemoryMenuSelected(app, event)
            report_memory ( app );
        end

        % Menu selected function: apptoMATLABbaseMenu
        function apptoMATLABbaseMenuSelected(app, event)
            assignin('base', 'MINFLUX_data_viewer', app);
        end

        % Menu selected function: removeallpreferencesMenu
        function removeallpreferencesMenuSelected(app, event)
            rmpref('mfx_viewer');
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MINFLUXDataViewerUIFigure = uifigure('Visible', 'off');
            app.MINFLUXDataViewerUIFigure.Name = 'MINFLUX Data Viewer';
            app.MINFLUXDataViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @MINFLUXDataViewerUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.MINFLUXDataViewerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenrawdatamatMenu = uimenu(app.FileMenu);
            app.OpenrawdatamatMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenrawdatamatMenuSelected, true);
            app.OpenrawdatamatMenu.Accelerator = 'o';
            app.OpenrawdatamatMenu.Text = 'Open raw data (.mat)';
            app.OpenrecentfilesMenu = uimenu(app.FileMenu);
            app.OpenrecentfilesMenu.Text = 'Open recent files';
            app.SaverawdatamatMenu = uimenu(app.FileMenu);
            app.SaverawdatamatMenu.MenuSelectedFcn = createCallbackFcn(app, @SaverawdatamatMenuSelected, true);
            app.SaverawdatamatMenu.Accelerator = 's';
            app.SaverawdatamatMenu.Text = 'Save raw data (.mat)';
            app.OpenconfocalimageMenu = uimenu(app.FileMenu);
            app.OpenconfocalimageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenconfocalimageMenuSelected, true);
            app.OpenconfocalimageMenu.Separator = 'on';
            app.OpenconfocalimageMenu.Text = 'Open confocal image';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.MINFLUXDataViewerUIFigure);
            app.EditMenu.Text = 'Edit';
            app.DataSelectorMenu = uimenu(app.EditMenu);
            app.DataSelectorMenu.MenuSelectedFcn = createCallbackFcn(app, @DataSelectorMenuSelected, true);
            app.DataSelectorMenu.Accelerator = 'i';
            app.DataSelectorMenu.Text = 'Data Selector';
            app.FilterMenu = uimenu(app.EditMenu);
            app.FilterMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterMenuSelected, true);
            app.FilterMenu.Separator = 'on';
            app.FilterMenu.Text = 'Filter';
            app.ROIMenu = uimenu(app.EditMenu);
            app.ROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIMenuSelected, true);
            app.ROIMenu.Text = 'ROI';
            app.PreferenceMenu = uimenu(app.EditMenu);
            app.PreferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferenceMenuSelected, true);
            app.PreferenceMenu.Separator = 'on';
            app.PreferenceMenu.Text = 'Preference';
            app.ViewerMenu = uimenu(app.MINFLUXDataViewerUIFigure);
            app.ViewerMenu.Text = 'Viewer';
            app.AttributePlotMenu = uimenu(app.ViewerMenu);
            app.AttributePlotMenu.MenuSelectedFcn = createCallbackFcn(app, @AttributePlotMenuSelected, true);
            app.AttributePlotMenu.Separator = 'on';
            app.AttributePlotMenu.Text = 'Attribute Plot';
            app.HistogramMenu = uimenu(app.ViewerMenu);
            app.HistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramMenu.Text = 'Histogram';
            app.ScatterPlotMenu = uimenu(app.ViewerMenu);
            app.ScatterPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @ScatterPlotMenuSelected, true);
            app.ScatterPlotMenu.Text = 'Scatter Plot';
            app.RenderImageMenu = uimenu(app.ViewerMenu);
            app.RenderImageMenu.MenuSelectedFcn = createCallbackFcn(app, @RenderImageMenuSelected, true);
            app.RenderImageMenu.Text = 'Render Image';
            app.StandardRenderMenu = uimenu(app.RenderImageMenu);
            app.StandardRenderMenu.MenuSelectedFcn = createCallbackFcn(app, @StandardRenderMenuSelected, true);
            app.StandardRenderMenu.Text = 'Standard Render';
            app.InteractiveRenderMenu = uimenu(app.RenderImageMenu);
            app.InteractiveRenderMenu.MenuSelectedFcn = createCallbackFcn(app, @InteractiveRenderMenuSelected, true);
            app.InteractiveRenderMenu.Text = 'Interactive Render';
            app.TraceViewMenu = uimenu(app.ViewerMenu);
            app.TraceViewMenu.MenuSelectedFcn = createCallbackFcn(app, @TraceViewMenuSelected, true);
            app.TraceViewMenu.Text = 'Trace View';
            app.ProcessMenu = uimenu(app.MINFLUXDataViewerUIFigure);
            app.ProcessMenu.Text = 'Process';
            app.AnalysisMenu = uimenu(app.MINFLUXDataViewerUIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.LocalizationPrecisionMenu = uimenu(app.AnalysisMenu);
            app.LocalizationPrecisionMenu.MenuSelectedFcn = createCallbackFcn(app, @LocalizationPrecisionMenuSelected, true);
            app.LocalizationPrecisionMenu.Text = 'Localization Precision';
            app.RIMFZscalefactorMenu = uimenu(app.AnalysisMenu);
            app.RIMFZscalefactorMenu.MenuSelectedFcn = createCallbackFcn(app, @RIMFZscalefactorMenuSelected, true);
            app.RIMFZscalefactorMenu.Text = 'RIMF (Z scale factor)';
            app.LocalDensityMenu = uimenu(app.AnalysisMenu);
            app.LocalDensityMenu.MenuSelectedFcn = createCallbackFcn(app, @LocalDensityMenuSelected, true);
            app.LocalDensityMenu.Text = 'Local Density';
            app.PluginsMenu = uimenu(app.MINFLUXDataViewerUIFigure);
            app.PluginsMenu.Text = 'Plugins';
            app.HelpMenu = uimenu(app.MINFLUXDataViewerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.MonitorMemoryMenu = uimenu(app.HelpMenu);
            app.MonitorMemoryMenu.MenuSelectedFcn = createCallbackFcn(app, @MonitorMemoryMenuSelected, true);
            app.MonitorMemoryMenu.Text = 'Monitor Memory';
            app.debugMenu = uimenu(app.HelpMenu);
            app.debugMenu.Text = 'debug';
            app.apptoMATLABbaseMenu = uimenu(app.debugMenu);
            app.apptoMATLABbaseMenu.MenuSelectedFcn = createCallbackFcn(app, @apptoMATLABbaseMenuSelected, true);
            app.apptoMATLABbaseMenu.Text = 'app to MATLAB base';
            app.removeallpreferencesMenu = uimenu(app.debugMenu);
            app.removeallpreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @removeallpreferencesMenuSelected, true);
            app.removeallpreferencesMenu.Text = 'remove all preferences';
            app.Toolbar = uitoolbar(app.MINFLUXDataViewerUIFigure);
            app.ROI_box = uipushtool(app.Toolbar);
            app.ROI_box.ClickedCallback = createCallbackFcn(app, @ROI_boxClicked, true);
            app.ROI_box.Icon = fullfile(pathToMLAPP, 'icons', 'rec.png');
            app.ROI_oval = uipushtool(app.Toolbar);
            app.ROI_oval.ClickedCallback = createCallbackFcn(app, @ROI_ovalClicked, true);
            app.ROI_oval.Icon = fullfile(pathToMLAPP, 'icons', 'ellipse.png');
            app.ROI_oval.Separator = 'on';
            app.ROI_polygon = uipushtool(app.Toolbar);
            app.ROI_polygon.ClickedCallback = createCallbackFcn(app, @ROI_polygonClicked, true);
            app.ROI_polygon.Icon = fullfile(pathToMLAPP, 'icons', 'polygon.png');
            app.ROI_polygon.Separator = 'on';
            app.ROI_freehand = uipushtool(app.Toolbar);
            app.ROI_freehand.ClickedCallback = createCallbackFcn(app, @ROI_freehandClicked, true);
            app.ROI_freehand.Icon = fullfile(pathToMLAPP, 'icons', 'free.png');
            app.ROI_freehand.Separator = 'on';
            app.ROI_line = uipushtool(app.Toolbar);
            app.ROI_line.ClickedCallback = createCallbackFcn(app, @ROI_lineClicked, true);
            app.ROI_line.Icon = fullfile(pathToMLAPP, 'icons', 'line.png');
            app.ROI_line.Separator = 'on';
            app.ROI_crosshair = uipushtool(app.Toolbar);
            app.ROI_crosshair.ClickedCallback = createCallbackFcn(app, @ROI_crosshairClicked, true);
            app.ROI_crosshair.Icon = fullfile(pathToMLAPP, 'icons', 'point.png');
            app.ROI_crosshair.Separator = 'on';
            app.LUT = uipushtool(app.Toolbar);
            app.LUT.ClickedCallback = createCallbackFcn(app, @LUTClicked, true);
            app.LUT.Icon = fullfile(pathToMLAPP, 'icons', 'lut.png');
            app.LUT.Separator = 'on';
            app.Color = uipushtool(app.Toolbar);
            app.Color.ClickedCallback = createCallbackFcn(app, @ColorClicked, true);
            app.Color.Icon = fullfile(pathToMLAPP, 'icons', 'color.png');
            app.Color.Separator = 'on';
            app.StatusTextArea = uitextarea(app.MINFLUXDataViewerUIFigure);
            app.StatusTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.MINFLUXDataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MINFLUX_data_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MINFLUXDataViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MINFLUXDataViewerUIFigure)
        end
    end
end