classdef dialog_LUT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        setupLUTUIFigure               matlab.ui.Figure
        LogscaleCheckBox               matlab.ui.control.CheckBox
        BackgroundDropDown             matlab.ui.control.DropDown
        BackgroundDropDownLabel        matlab.ui.control.Label
        ReverseCheckBox                matlab.ui.control.CheckBox
        LUTLimitminmaxSlider           matlab.ui.control.RangeSlider
        ColormapLimitLabel             matlab.ui.control.Label
        ApplyColormaptoFiguresListBox  matlab.ui.control.ListBox
        ApplyColormaptoFiguresListBoxLabel  matlab.ui.control.Label
        ApplyColormapButton            matlab.ui.control.Button
        ColorbyValueDropDown           matlab.ui.control.DropDown
        ColorbyvalueLabel              matlab.ui.control.Label
        LUTColormapDropDown            matlab.ui.control.DropDown
        ColormapLUTLabel               matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %skip_tags = ["dialog_info", "dialog_LUT", "dialog_preference", "dialog_filter", "figure_attribute", "figure_histogram"];
        tag_whitelist = ["figure_scatter", "figure_render"];
        window_all
        data_scatter
        handle_scatter
        % data_render
        % render_handle
    end
    
    properties (Access = public)
        CallingApp                              % MINFLUX Viewer main app
        Name = "dialog_LUT";
        Tag = 'dialog_LUT'; % Description % Description
    end
    
    methods (Access = private)
        
        function fig = get_selected_figure (app, fig_name)
            name_check = cellfun(@(x) strcmp(x.Name, fig_name), app.window_all, 'uni', 0);
            idx = find([name_check{:}]);
            if idx == 0 
                return;
            end
            fig = app.window_all{idx};
            if iscell(fig)
                fig = fig{1};
            end
        end

        function cmap_idx = retrieve_cmap_index (~, cmap)
            %cmaps = app.LUTColormapDropDown.Items;

            cmaps = {
                @hot,  @autumn, @summer, @gray, @sky, @abyss, @jet, ...
                @parula, @turbo, @glasbey, @hsv, @bone, @copper, @pink, ...
                @flag, @lines, @colorcube, @prism, @cool, @spring, ...
                @winter, @white };

            %cmaps(1) = [];
            cmap_idx = 1;
            num_color = size(cmap, 1); % how many colors

            for i = 1 : length(cmaps)
                %cmap_i = colormap( cmaps{i} );
                %cmap_i = cmap_i(num_color);
                cmap_i = cmaps{i}(num_color);
                if isequal(cmap, cmap_i)
                    cmap_idx = i + 1;
                    return;
                end
            end
        end

        
        function data_scatter = make_scatter_sample (app)
            N = 1e4;
            if ~isempty(app.ApplyColormaptoFiguresListBox.Items)
                if isempty(app.ApplyColormaptoFiguresListBox.Value)
                    data_name = extractAfter(app.ApplyColormaptoFiguresListBox.Items{1}, " : ");
                else
                    data_name = extractAfter(app.ApplyColormaptoFiguresListBox.Value, " : ");
                end
                
                idx = find_data_index (app.CallingApp, data_name);
                if ~isempty(idx)
                    N = app.CallingApp.data{idx}.prop.num_loc;
                end
            end
            sigma = 55;     % standard deviation as of from 256x256 Gaussian kernel with sigma=64;
            xyval = int16( normrnd(0, sigma, N, 2) );  % x, y coordinates random sampling from a 2D Guassian 
            cval = round( 255 * exp(-(sum(xyval.^2, 2) / (2*sigma^2)))); % color value range between 1 - 256
            data_scatter = [xyval, cval];  % horizontal combined xyc array, int 16
        end

        function data_render = make_render_sample (app)
            size = 256;
            if ~isempty(app.ApplyColormaptoFiguresListBox.Items)
                if isempty(app.ApplyColormaptoFiguresListBox.Value)
                    data_name = extractAfter(app.ApplyColormaptoFiguresListBox.Items{1}, " : ");
                else
                    data_name = extractAfter(app.ApplyColormaptoFiguresListBox.Value, " : ");
                end
                idx = find_data_index (app.CallingApp, data_name);
                if ~isempty(idx)
                    size = max( range( app.CallingApp.data{idx}.prop.xyz ) ) / 64;  % xyz in nm
                end
            end
            img = fspecial( "gaussian", [256, 256], 64 );
            img = imresize( img, [size, size/2] );
            data_render = round( rescale(img, 0, 255) );
        end
        

        function set_cdata_value (app, val)
            if islogical(val)
                %if value is logical: vld, ftr, act
                disp("input color by value is logical.");
            end
            
            val = val(:);
            val_range = range( val );
            val_min = double( min( app.data_scatter(:, 3) ) );
            val_max = double( max( app.data_scatter(:, 3) ) );
            if ~isnan(val_range) && val_range ~= 0
                val_min = double( min(val) );
                val_max = double( max(val) );
            end
           
            % set cdata range to [val_min, val_max];
            app.handle_scatter.CData = rescale(app.data_scatter(:, 3), val_min, val_max);
            %imgdata = app.render_handle.CData;
            %imgdata = rescale(imgdata, val_min, val_max);
            %app.render_handle.CData = imgdata;
            
            app.LUTLimitminmaxSlider.Limits = [val_min, val_max];
            app.LUTLimitminmaxSlider.Value = [val_min, val_max];
            app.handle_scatter.CData = rescale(app.handle_scatter.CData, val_min,val_max);
            app.UIAxes.CLim = [val_min, val_max];

        end

    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            app.CallingApp = mainapp;
            if isempty(app.CallingApp.children_apps)
                return;
            end
            
            % get figure window's name, whose tag in the white list
            app.window_all = app.CallingApp.children_apps; %findall(groot(), 'Type', 'figure');
            tag_match = cellfun(@(win) strcmp(win.Tag, app.tag_whitelist), app.window_all, 'uni', 0);
            match = any( cell2mat( tag_match' ), 2);
            app.window_all( find(~match) ) = [];
            names_figure = cellfun(@(win) win.Name, app.window_all, 'uni', 0);
            app.ApplyColormaptoFiguresListBox.Items = names_figure;
            
            try
                items = app.ColorbyValueDropDown.Items;
                idx = get_active_data_index (app.CallingApp);
                if ~isempty(idx)
                    app.ColorbyValueDropDown.Items = [items; app.CallingApp.data{idx}.prop.attr_names];
                end
            catch
            end

            % add context menu to colormap limit range slider
            cm = uicontextmenu(app.setupLUTUIFigure);
            uimenu(cm, "Text", "setup range", "MenuSelectedFcn", @setup_range);
            app.LUTLimitminmaxSlider.ContextMenu = cm;
            function setup_range (~, ~)
                value = app.LUTLimitminmaxSlider.Limits;
                prompt = {'min value:', 'max value:'};
                dlgtitle = 'Setup Colormap Range Limits';
                fieldsize = [1 25; 1 25];
                definput = {string(value(1)), string(value(2))};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                if isempty(answer)
                    return;
                end
                value(1) = str2double(answer{1});
                value(2) = str2double(answer{2});
                app.LUTLimitminmaxSlider.Limits = value;
            end
            
            % make sample plot to preview the colormap : scatter of localization coordinate
            app.data_scatter = make_scatter_sample (app);
            app.handle_scatter = scatter(app.UIAxes, app.data_scatter(:, 1), 2*app.data_scatter(:, 2), 3, app.data_scatter(:, 3));
            
            % % make sample plot to preview the colormap : image (for rendered image)
            % app.data_render = make_render_sample (app);
            % app.handle_render = imshow(app.data_render, [], 'Parent', app.UIAxes);


            app.UIAxes.XAxis.Visible = 'off';
            app.UIAxes.YAxis.Visible = 'off';
            axis( app.UIAxes, 'image' );
            colormap( app.UIAxes, 'parula' );
            colorbar( app.UIAxes );
            
        end

        % Double-clicked callback: ApplyColormaptoFiguresListBox
        function ApplyColormaptoFiguresListBoxDoubleClicked(app, event)
            idx_selected = event.InteractionInformation.Item;
            fig_name = app.ApplyColormaptoFiguresListBox.Items{idx_selected};
            fig = get_selected_figure (app, fig_name);
            if isempty(fig)
                return;
            end

            % get selected figure, axes, and colormap
            ax = findall(fig, 'type', 'axes'); % assume only one plot axes in figure
            cmap_selected = colormap( ax );
            %axeslink = linkprop([ax, app.UIAxes], {'Colormap', 'Clim', 'ColorScale', 'Color'});
            
            % Synchronize scatter plot data length and CData
            app.data_scatter = make_scatter_sample (app);
            app.handle_scatter = scatter(app.UIAxes, app.data_scatter(:, 1), 2*app.data_scatter(:, 2), 3, app.data_scatter(:, 3));
            
            % Synchronize UI axes colormap
            app.UIAxes.Colormap = cmap_selected;


            %set_cdata_value (app, scatter_plot.CData);
            %plotlink = linkprop([scatter_plot, app.handle_scatter], {'CData'});


            % cmap_selected = colormap( ax );
            % %scatter_plot = findobj(ax, 'Type', 'scatter');
            % %val = double( scatter_plot.CData );
            % 
            
            % Background: find axes background color, and update background selection
            app.UIAxes.Color = ax.Color;
            if isequal(ax.Color, [1, 1, 1]) % white background
                app.BackgroundDropDown.Value = 'white';
            elseif isequal(ax.Color, [0, 0, 0]) % black background
                app.BackgroundDropDown.Value = 'black';
            elseif isequal(ax.Color, [0.75, 0.75, 0.75])
                app.BackgroundDropDown.Value = 'gray';
            else    % adapt in the future for more background colors
            
            end
            
            % LUT: find retrieved colormap, and update colormap dropdown value
            cmap_idx = retrieve_cmap_index (app, cmap_selected);
            app.LUTColormapDropDown.Value = app.LUTColormapDropDown.Items{cmap_idx};
            
            % Color by Value: find colorbar, and get its label as the color by attribute
            cb = findobj(fig, 'Type', 'colorbar');
            cval_str = cb.Label.String;
            if strcmp(cval_str, 'density')
                cval_str = "den";
            end
            cval_idx = find( strcmp(app.ColorbyValueDropDown.Items, cval_str), 1 );
            if ~isempty(cval_idx)
                app.ColorbyValueDropDown.Value = app.ColorbyValueDropDown.Items{cval_idx};
            end
            
            % LUT Limit: find colormap limit
            app.UIAxes.CLim = ax.CLim;
            clim_selected = double( ax.CLim );
            if ~isnan(range(clim_selected)) && range(clim_selected) ~= 0
                app.LUTLimitminmaxSlider.Limits = clim_selected;
                app.LUTLimitminmaxSlider.Value = clim_selected;
                app.handle_scatter.CData = rescale(app.handle_scatter.CData, clim_selected(1), clim_selected(2));
                app.UIAxes.CLim = clim_selected;
            end
            
            % Reverse and Log scale buttons
            app.ReverseCheckBox.Value = false;
            app.UIAxes.ColorScale = ax.ColorScale;
            if strcmp(ax.ColorScale', 'log')
                app.LogscaleCheckBox.Value = true;
            else
                app.LogscaleCheckBox.Value = false;
            end
            
            
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            bg_color = [1, 1, 1];   % default background color is white
            switch app.BackgroundDropDown.Value
                case 'white'
                    bg_color = [1, 1, 1];
                case 'black'
                    bg_color = [0, 0, 0];
                case 'gray'
                    bg_color = [0.75, 0.75, 0.75];
            end
            set(app.UIAxes, 'Color', bg_color);
        end

        % Value changed function: LUTColormapDropDown
        function LUTColormapDropDownValueChanged(app, event)
            cmap_name = app.LUTColormapDropDown.Value;
            if strcmp(cmap_name, 'glasbey')
                colormap( app.UIAxes, glasbey() );
            else
                colormap( app.UIAxes, cmap_name );
            end
            app.UIAxes.Title.String = string(cmap_name);
            app.ReverseCheckBox.Value = false;
            app.LogscaleCheckBox.Value = false;
        end

        % Value changed function: ColorbyValueDropDown
        function ColorbyValueDropDownValueChanged(app, event)
            app.ReverseCheckBox.Value = false;
            app.LogscaleCheckBox.Value = false;

            idx = get_active_data_index( app.CallingApp );
            if isempty(idx)
                app.ColorbyValueDropDown.Items = {"auto"};
                app.ColorbyValueDropDown.Value = "auto";
            end

            % update CData value
            if isequal(app.ColorbyValueDropDown.Value, "auto")
                % automatically set the cdata and clim
                app.UIAxes.CLimMode = 'auto';
                set_cdata_value( app, 0 );
            else
                app.UIAxes.CLimMode = 'manual';
                val = app.CallingApp.data{idx}.attr.(app.ColorbyValueDropDown.Value);
                set_cdata_value (app, val);
            end

        end

        % Value changed function: ReverseCheckBox
        function ReverseCheckBoxValueChanged(app, event)
            cmap = colormap( app.UIAxes );
            colormap( app.UIAxes, flipud(cmap) );
        end

        % Value changed function: LogscaleCheckBox
        function LogscaleCheckBoxValueChanged(app, event)
            if app.LogscaleCheckBox.Value
                set(app.UIAxes, 'ColorScale', 'log');
            else
                set(app.UIAxes, 'ColorScale', 'linear');
            end
        end

        % Value changed function: LUTLimitminmaxSlider
        function LUTLimitminmaxSliderValueChanged(app, event)
            clim( app.UIAxes, app.LUTLimitminmaxSlider.Value );
        end

        % Button pushed function: ApplyColormapButton
        function ApplyColormapButtonPushed(app, event)
            % get selected figure by name list selection
            fig = get_selected_figure (app, app.ApplyColormaptoFiguresListBox.Value);
            if isempty(fig)
                return;
            end
            
            % get current colormap: name, fliped? limit?
            cmap = app.UIAxes.Colormap;
            clim = app.UIAxes.CLim;
            ax = findobj(fig, 'Type', 'Axes');
            ax.Colormap = cmap;
            ax.CLim = clim;
            if app.LogscaleCheckBox.Value
                ax.ColorScale = 'log';
            else
                ax.ColorScale = 'linear';
            end
            switch app.BackgroundDropDown.Value
                case 'white'
                    ax.Color = [1, 1, 1];
                case 'black'
                    ax.Color = [0, 0, 0];
                case 'gray'
                    ax.Color = [0.75, 0.75, 0.75];
            end
            
            cval = app.ColorbyValueDropDown.Value;
            if isequal(cval, 'auto')
                return;
            end


            data_name = extractAfter(app.ApplyColormaptoFiguresListBox.Value, " : ");
            idx = find_data_index (app.CallingApp, data_name);
            %idx = get_active_data_index( app.CallingApp );
            if isempty(idx)
                return;
            end
            cdata = app.CallingApp.data{idx}.attr.(cval); %(ftr, :);

            switch fig.Tag

                case "figure_scatter"
                    scatter_plot = findobj(fig, 'Type', 'scatter');
                    scatter_plot.CData = cdata;
                    cb = findobj(fig, 'Type', 'colorbar');
                    cb.Label.String = cval;

                case "figure_render" % most probably will create error!!!
                    image_handle = findobj(fig, 'Type', 'image');
                    image_handle.CData = cdata;
            end
            

        end

        % Close request function: setupLUTUIFigure
        function setupLUTUIFigureCloseRequest(app, event)
            close_child_app (app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.setupLUTUIFigure = uifigure('Visible', 'off');
            app.setupLUTUIFigure.Name = 'setup LUT';
            app.setupLUTUIFigure.CloseRequestFcn = createCallbackFcn(app, @setupLUTUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.setupLUTUIFigure);
            title(app.UIAxes, 'Colormap Preview')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.ColormapLUTLabel = uilabel(app.setupLUTUIFigure);
            app.ColormapLUTLabel.Text = 'LUT (Colormap):';
            app.LUTColormapDropDown = uidropdown(app.setupLUTUIFigure);
            app.LUTColormapDropDown.Items = {'default', 'hot', 'autumn', 'summer', 'gray', 'sky', 'abyss', 'jet', 'parula', 'turbo', 'glasbey', 'hsv', 'bone', 'copper', 'pink', 'flag', 'lines', 'colorcube', 'prism', 'cool', 'spring', 'winter', 'white'};
            app.LUTColormapDropDown.Editable = 'on';
            app.LUTColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTColormapDropDownValueChanged, true);
            app.LUTColormapDropDown.Value = 'default';
            app.ColorbyvalueLabel = uilabel(app.setupLUTUIFigure);
            app.ColorbyvalueLabel.Text = 'Color by  Value :';
            app.ColorbyValueDropDown = uidropdown(app.setupLUTUIFigure);
            app.ColorbyValueDropDown.Items = {'auto'};
            app.ColorbyValueDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorbyValueDropDownValueChanged, true);
            app.ColorbyValueDropDown.Value = 'auto';
            app.ApplyColormapButton = uibutton(app.setupLUTUIFigure, 'push');
            app.ApplyColormapButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyColormapButtonPushed, true);
            app.ApplyColormapButton.Text = 'Apply Colormap';
            app.ApplyColormaptoFiguresListBoxLabel = uilabel(app.setupLUTUIFigure);
            app.ApplyColormaptoFiguresListBoxLabel.Text = 'Apply Colormap to Figure(s)';
            app.ApplyColormaptoFiguresListBox = uilistbox(app.setupLUTUIFigure);
            app.ApplyColormaptoFiguresListBox.Items = {};
            app.ApplyColormaptoFiguresListBox.DoubleClickedFcn = createCallbackFcn(app, @ApplyColormaptoFiguresListBoxDoubleClicked, true);
            app.ApplyColormaptoFiguresListBox.Value = {};
            app.ColormapLimitLabel = uilabel(app.setupLUTUIFigure);
            app.ColormapLimitLabel.Text = 'LUT Limit : min - max';
            app.LUTLimitminmaxSlider = uislider(app.setupLUTUIFigure, 'range');
            app.LUTLimitminmaxSlider.Limits = [0 255];
            app.LUTLimitminmaxSlider.ValueChangedFcn = createCallbackFcn(app, @LUTLimitminmaxSliderValueChanged, true);
            app.LUTLimitminmaxSlider.Tooltip = {''};
            app.LUTLimitminmaxSlider.Value = [0 255];
            app.ReverseCheckBox = uicheckbox(app.setupLUTUIFigure);
            app.ReverseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseCheckBoxValueChanged, true);
            app.ReverseCheckBox.Text = 'Reverse';
            app.BackgroundDropDownLabel = uilabel(app.setupLUTUIFigure);
            app.BackgroundDropDownLabel.Text = 'Background :';
            app.BackgroundDropDown = uidropdown(app.setupLUTUIFigure);
            app.BackgroundDropDown.Items = {'white', 'black', 'gray'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Value = 'white';
            app.LogscaleCheckBox = uicheckbox(app.setupLUTUIFigure);
            app.LogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBoxValueChanged, true);
            app.LogscaleCheckBox.Text = 'Log scale';
            % Show the figure after all components are created
            app.setupLUTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_LUT(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.setupLUTUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.setupLUTUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.setupLUTUIFigure)
        end
    end
end