classdef dialog_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AttributeFilterUIFigure  matlab.ui.Figure
        loadFilterButton         matlab.ui.control.Button
        saveFilterButton         matlab.ui.control.Button
        addFilterButton          matlab.ui.control.Button
        deleteFilterButton       matlab.ui.control.Button
        displayFilterButton      matlab.ui.control.Button
        UITableFilter            matlab.ui.control.Table
    end

    
    properties (Access = private)
        fig_histogram
        filter_idx_selected
        min_line % Description
        max_line
    end
    
    properties (Access = public)
        CallingApp                              % MINFLUX Viewer main app
        Name = "dialog_filter";
        Tag = 'dialog_filter';                  % identifier
        %filters                                 % would be same to CallingApp.filters (? unnecessary?)
        data                                    % active data to apply filter on
    end
    
    methods (Access = private)
        
        function set_data (app)
            % currently this is the only place to set the active data the
            % filter will work on;
            app.data = [];
            % if switch data, the filter dialog need to be close and reopen
            idx = get_active_data_index (app.CallingApp);
            if ~isempty(idx)
                app.data = app.CallingApp.data{idx};
            end
            
            %app.filters = struct;   % reset filters to empty struct
            
            % reset filter arrays 
            if ~isempty(app.CallingApp.filters)
                for i = 1 : size(app.CallingApp.filters, 1)
                    app.CallingApp.filters{i, 6} = [];
                end
            end
        end

        function filter_to_UI (app, filters)
            % only append filters to the end of the filter UI table
            % if isempty(filters) % filters is empty struct
            %     return
            % end
            for i = 1 : size(filters, 1)
                table_data_i = filters(i, 1:5);
                %app.UITableFilter.Data(end+1, :) = filter(1:5);
        
                if isempty(app.UITableFilter.Data)
                    app.UITableFilter.Data = table_data_i;
                else
                    app.UITableFilter.Data(end+1, :) = table_data_i;
                end
            end
        end


        function find_histogram_figure (app)
            idx = get_active_data_index(app.CallingApp);
            if isempty(idx)
                app.fig_histogram = [];
                return;
            end
            data_name = app.CallingApp.data{idx}.file.name;
            fig_name = strcat("Attribute Histogram : ", data_name);
            app.fig_histogram = findall(0, 'Type', 'figure', 'Name', fig_name);

            % window_all = app.CallingApp.children_apps;
            % try
            %     for i = 1 : length(window_all)
            %         if ~isvalid(window_all{i})
            %             continue;
            %         end
            %         if isequal(window_all{i}.Tag, "figure_histogram")
            %             app.fig_histogram = window_all{i};
            %             break;
            %         end
            %     end
            % catch
            % end

            if isempty(app.fig_histogram)
                app.fig_histogram = plot_histogram (app.CallingApp);
                app.CallingApp.children_apps{end+1} = app.fig_histogram;
            end
        end


        function create_interactive_filter (app, boundsValue)
            % app.fig_histogram should already be checked before calling of this function

            ax = findobj(app.fig_histogram, 'Type', 'Axes');
            % remove any pre-exist bounds lines, to allow only 1-pair of
            % bounds line for the active filter:
            delete ( findobj(ax, 'Type', 'constantline') );
            % xlim = ax.XLim; xrange = range(xlim);
            % minValue = xlim(1) + 0.05 *xrange;
            % maxValue = xlim(2) - 0.05 *xrange;
            hist = findobj(app.fig_histogram, 'Type', 'histogram');

            if nargin < 2
                minValue = hist.BinLimits(1);
                maxValue = hist.BinLimits(2);
            else
                minValue = boundsValue(1);
                maxValue = boundsValue(2);
            end

            % Initialize lower and upper bounds using xline
            lower_bound = xline(ax, minValue, 'r--', 'LineWidth', 1.5, ...
                'Label', 'Min', 'LabelOrientation', 'horizontal', 'LabelHorizontalAlignment', 'center', 'LabelVerticalAlignment', 'middle', ...
                'Tag', 'filter_min', 'buttonDownFcn', @startDragFcn);
            upper_bound = xline(ax, maxValue, 'r--', 'LineWidth', 1.5, ...
                'Label', 'Max', 'LabelOrientation', 'horizontal', 'LabelHorizontalAlignment', 'center', 'LabelVerticalAlignment', 'middle', ...
                'Tag', 'filter_max', 'buttonDownFcn', @startDragFcn);
            
            function startDragFcn(src, ~)
                switch app.fig_histogram.SelectionType
                    case 'normal'                                               % left click
                        set(app.fig_histogram,'WindowButtonMotionFcn',{@draggingFcn, src});
                    case 'alt'                                                  % right click
                        clear_fig_button_Fcn();         % show context menu
                    case 'open'                                                 % double click
                        clear_fig_button_Fcn();
                        set_value();                    % open set value dialog
                end
            end

            function draggingFcn(~, ~, src_line)
                current_point = ax.CurrentPoint(1, 1); % Get the x-coordinate of the mouse click
                src_line.Value = current_point;
                src_line.Label = num2str(src_line.Value, '%.2g'); %strcat(extractBefore(src_line.Label, 4), ": ", num2str(src_line.Value, '%.2e'));
                set(app.fig_histogram, 'WindowButtonUpFcn', @stopDragging);
            end
            
            function stopDragging(~, ~)
                lower_bound.Label = "Min";
                upper_bound.Label = "Max";
                clear_fig_button_Fcn();
            end
            
            function clear_fig_button_Fcn ()
                set(app.fig_histogram, 'WindowButtonMotionFcn', '');  % Clear the motion function
                set(app.fig_histogram, 'WindowButtonUpFcn', '');      % Clear the up function
            end

            % Create context Menu cooresponding to right mouse click
            cm = uicontextmenu(app.fig_histogram);
            uimenu(cm, "Text", "add to filter list", "MenuSelectedFcn", @add_to_table);
            uimenu(cm, "Text", "set min / max value", "MenuSelectedFcn", @set_value);
            uimenu(cm, "Text", "cancel", "MenuSelectedFcn", @delete_lines);
            lower_bound.ContextMenu = cm;
            upper_bound.ContextMenu = cm;
        
            
            function add_to_table(~, ~)
                if upper_bound.Value < lower_bound.Value
                     [lower_bound, upper_bound] = deal(upper_bound, lower_bound);
                end
                attr_list = findobj(app.fig_histogram, 'Type', 'uidropdown', 'Tag', 'attr_list');
                var_type = findobj(app.fig_histogram, 'Type', 'uidropdown', 'Tag', 'var_type');
                new_filter = {true, attr_list.Value, var_type.Value, lower_bound.Value, upper_bound.Value};
                
                if isempty(app.UITableFilter.Data)
                    app.UITableFilter.Data = new_filter;
                else
                    app.UITableFilter.Data(end+1, :) = new_filter;
                end
                
                clear_fig_button_Fcn();
                delete(lower_bound);
                delete(upper_bound);

                % TODO: then apply the filter to rest data related plot and figures
                ftr = compute_filter_array(app.data, new_filter);
                new_filter{1, 6} = ftr;
                app.CallingApp.filters(end+1, :) = new_filter;

            end
        
            function set_value(~, ~)
                prompt = {'Min value:', 'Max value:'}; % inclusive or exclusive
                title = 'set min / max values:';
                dims = [1, 45];
                definput = { num2str(lower_bound.Value, '%.2f'), num2str(upper_bound.Value, '%.2f') };
                answer = inputdlg(prompt, title, dims, definput);
                if isempty(answer)
                    return;
                end
                lower_bound.Value = str2double(answer{1});
                upper_bound.Value = str2double(answer{2});
            end
        
            function delete_lines(~, ~)
                clear_fig_button_Fcn();
                delete(lower_bound);
                delete(upper_bound);
            end

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            % add drag and drop functionality to UI
            enable_drag_and_drop (app.AttributeFilterUIFigure, @file_dropped);
            %load Preferences
            %app.Prefs = load_prefs();

            % check if there's active data, copy it to local data property
            % remove pre-exist filter array from main app
            set_data (app);     % get active data

            % use data filter property to show exist filters
            filter_to_UI (app, app.CallingApp.filters);
  
            %call-back function to handel drag and drop event
            function file_dropped (~, evt)
                if ~endsWith(evt.names{1}, '.json')
                    return;
                end
                [folder, file, ext] = fileparts(evt.names{1});
                load_filter (app.CallingApp, strcat(file, ext), folder);
                filter_to_UI (app, app.CallingApp.filters);
            end

        end

        % Button pushed function: loadFilterButton
        function loadFilterButtonPushed(app, event)
            %idx = get_active_data_index (app.CallingApp);
            %if isempty(idx)
            %    disp("loading filter without any pre-existing data!");
            %end

            if isfield(app.CallingApp.Prefs.file, "default_folder")
                [file, folder] = uigetfile('*.json', "select filter .json file", app.CallingApp.Prefs.file.default_folder);
            else
                [file, folder] = uigetfile('*.json', "select filter .json file");
            end

            load_filter (app.CallingApp, file, folder);
            filter_to_UI (app, app.CallingApp.filters);
        end

        % Button pushed function: saveFilterButton
        function saveFilterButtonPushed(app, event)
            if isempty(app.CallingApp.filters)
                return;
            end

            idx = get_active_data_index (app.CallingApp);
            
            if isempty(idx)
                folder = app.CallingApp.Prefs.file.default_folder;
                [save_file, save_path, indx] = uiputfile(fullfile(folder, '_filter.json'), 'save filter as json file');
            else
                file = app.CallingApp.data{idx}.file;
                [~, name, ~] = fileparts(file.name);
                [save_file, save_path, indx] = uiputfile(fullfile(file.folder, strcat(name, '_filter.json')), 'save filter as json file');
            end
            
            if indx == 0
                return;
            end

            header = ["apply", "attribute", "value_as", "min", "max"];
            jsonData = cell2struct(app.UITableFilter.Data, header, 2);
            jsonText = jsonencode(jsonData, 'PrettyPrint', true);

            fid = fopen(fullfile(save_path, save_file), 'w');
            fprintf(fid, '%s', jsonText);
            fclose(fid);

        end

        % Button pushed function: addFilterButton
        function addFilterButtonPushed(app, event)
            % 1, use pre-cached active data
            if isempty(app.data)
                return;
            end
            % 2, use pre-cached histogram figure handle
            if isempty(app.fig_histogram) || ~isvalid(app.fig_histogram)
                find_histogram_figure (app);
            end
            % 3, add the min and max value vertical line
            create_interactive_filter(app);%, app.fig_histogram, ax, hist_plot);
            % 5, add listener and context menu to the vertical lines

        end

        % Button pushed function: deleteFilterButton
        function deleteFilterButtonPushed(app, event)

            if isempty(app.UITableFilter.Data) || isempty(app.filter_idx_selected) %|| isempty(app.CallingApp.filters)
                return;
            end
            %tdata = app.UITableFilter.Data;
            app.UITableFilter.Data(app.filter_idx_selected, :) = [];
            %app.UITableFilter.Data = tdata;
            app.CallingApp.filters(app.filter_idx_selected, :) = [];

        end

        % Button pushed function: displayFilterButton
        function displayFilterButtonPushed(app, event)
            %   display selected filter on active data histogram plot:
            %   serve also as a way to refresh active data:
            %   1, get active data;
            %   2, check and get the histogram figure handle;
            %   3, change histogram UI to the selected filter property;
            %   4, add the min and max value vertical line
            %   5, add listener and context menu to the vertical lines


            % get selected filter in table
            if isempty(app.UITableFilter.Data) || isempty(app.filter_idx_selected)
                return;
            end

            filter_selected = app.UITableFilter.Data(app.filter_idx_selected, :);
            apply = filter_selected{1};

            % locate histogram plot window
            if isempty(app.fig_histogram) || ~isvalid(app.fig_histogram)
                find_histogram_figure (app);
            end

            % display selected filter
            %app_data = app.CallingApp.data;
            %filter = app_data.filters.filter;
           
            ax = findobj(app.fig_histogram, 'Type', 'Axes');
            attr_list = findobj(app.fig_histogram, 'Tag', 'attr_list');
            attr_list.Value = filter_selected{2};
            var_type = findobj(app.fig_histogram, 'Tag', 'var_type');
            var_type.Value = filter_selected{3};
            % trigger value changed function on histogram plot figure
            event = struct('Value', filter_selected{2}, 'PreviousValue', attr_list.Value, ...
               'Edited', 1, 'Source', app, 'EventName', 'ValueChanged');
            attr_list.ValueChangedFcn(app, event);
            

            %hist_plot = findobj(app.fig_histogram, 'Type', 'histogram');


            create_interactive_filter(app, [filter_selected{4:5}]);%, app.fig_histogram, ax, hist_plot);


        end

        % Cell selection callback: UITableFilter
        function UITableFilterCellSelection(app, event)
            app.filter_idx_selected = event.Indices(1, 1); % row selection indices return 2,1; 2,2; 2,3; 2,4; 2,5
        end

        % Cell edit callback: UITableFilter
        function UITableFilterCellEdit(app, event)
            % apply filter enabled here
            %   1, check and get active data: if no data exist, uncheck all apply boxes
            %   2, update cached ftr array for the row being edited: when min/max value change
            %   3, combine all ftr array for applied rows: logical AND
            %   4, update active data attribute ftr: data.attr.ftr
            %   5, update active data plots: 
            %       attribute plot, histogram, scatter, ? render image

            % if no MINFLUX data is loaded, uncheck all apply boxes
            if isempty(app.CallingApp.data)
                for i = 1 : size(app.UITableFilter.Data, 1)
                    app.UITableFilter.Data{i, 1} = false;
                end
                return;
            end
            
            % check which cell is being edited: 1:apply; 4:min; 5:max
            evt_idx = event.Indices;
            if evt_idx(2) ~= 1 % min or max value changed
                app.UITableFilter.Data{evt_idx(1), 1} = false;  % uncheck the filter being edited
                % recompute ftr array of this filter
                ftr_row = compute_filter_array (app.data, app.UITableFilter.Data(evt_idx(1), :));
                app.CallingApp.filters{evt_idx(1), 6} = ftr_row;
            else                % change on apply or not-apply the selected filter
                app.CallingApp.filters{evt_idx(1), 1} = event.NewData;
            end
            
            app.data.attr.ftr = true( app.data.prop.num_loc, 1 ); % filter index?
            for i = 1 : size(app.CallingApp.filters, 1)
                if app.CallingApp.filters{i, 1}
                    if isempty(app.CallingApp.filters{i, 6})
                        app.CallingApp.filters{i, 6} = compute_filter_array (...
                            app.data, app.CallingApp.filters(i, 1:5));
                    end
                    app.data.attr.ftr = app.data.attr.ftr & app.CallingApp.filters{i, 6};
                end
            end
            % update the ftr attribute in the active data
            idx = get_active_data_index(app.CallingApp);
            app.CallingApp.data{idx}.attr.ftr = app.data.attr.ftr;


            % apply filter array to data, in all plots:
            % attribute plot, histogram, scatter plot, render image
            apply_filter_to_data (app.CallingApp);
        end

        % Close request function: AttributeFilterUIFigure
        function AttributeFilterUIFigureCloseRequest(app, event)
            close_child_app ( app );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AttributeFilterUIFigure = uifigure('Visible', 'off');
            app.AttributeFilterUIFigure.Name = 'Attribute Filter';
            app.AttributeFilterUIFigure.CloseRequestFcn = createCallbackFcn(app, @AttributeFilterUIFigureCloseRequest, true);
            app.UITableFilter = uitable(app.AttributeFilterUIFigure);
            app.UITableFilter.ColumnName = {'apply'; 'attribute'; 'value as'; 'min'; 'max'};
            app.UITableFilter.RowName = {};
            app.UITableFilter.SelectionType = 'row';
            app.UITableFilter.ColumnEditable = [true false false true true];
            app.UITableFilter.CellEditCallback = createCallbackFcn(app, @UITableFilterCellEdit, true);
            app.UITableFilter.CellSelectionCallback = createCallbackFcn(app, @UITableFilterCellSelection, true);
            app.UITableFilter.Multiselect = 'off';
            app.displayFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.displayFilterButton.ButtonPushedFcn = createCallbackFcn(app, @displayFilterButtonPushed, true);
            app.displayFilterButton.Text = 'display';
            app.deleteFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.deleteFilterButton.ButtonPushedFcn = createCallbackFcn(app, @deleteFilterButtonPushed, true);
            app.deleteFilterButton.Text = '-';
            app.addFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.addFilterButton.ButtonPushedFcn = createCallbackFcn(app, @addFilterButtonPushed, true);
            app.addFilterButton.Text = {'+'; ''};
            app.saveFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.saveFilterButton.ButtonPushedFcn = createCallbackFcn(app, @saveFilterButtonPushed, true);
            app.saveFilterButton.Text = 'save';
            app.loadFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.loadFilterButton.ButtonPushedFcn = createCallbackFcn(app, @loadFilterButtonPushed, true);
            app.loadFilterButton.Text = 'load';
            % Show the figure after all components are created
            app.AttributeFilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_filter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AttributeFilterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AttributeFilterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AttributeFilterUIFigure)
        end
    end
end