classdef dialog_info < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataSelectorUIFigure  matlab.ui.Figure
        UITable               matlab.ui.control.Table
        ContextMenu           matlab.ui.container.ContextMenu
        propertiesMenu        matlab.ui.container.Menu
        selectMenu            matlab.ui.container.Menu
        deleteMenu            matlab.ui.container.Menu
    end

    
    properties (Access = public)
        CallingApp                              % MINFLUX Viewer main app
        Name = "dialog_info";
        Tag = 'dialog_info'; % Description
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        

        function data_to_UI (app, data)
            % populate UI Table with current data in app (viewer main app)
            if isempty(data)
                return
            end
            for i = 1 : numel(data)
                data_i = data{i};
                active = data_i.active;
                name = convertStringsToChars( data_i.file.name );
                data_row = {active, name};
                if isempty(app.UITable.Data)
                    app.UITable.Data = data_row;
                else
                    app.UITable.Data(end+1, :) = data_row;
                end
            end
        end


    end
    
    methods (Access = public)
        
        function append_new_data (app, new_data)
            if isempty(new_data)
                return
            end
            
            active = true;
            name = convertStringsToChars( new_data.file.name );
            data_row = {active, name};

            if isempty(app.UITable.Data)
                app.UITable.Data = data_row;
            else
                for i = 1 : size(app.UITable.Data, 1)
                    app.UITable.Data{i, 1} = false;
                end
                app.UITable.Data(end+1, :) = data_row;
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            % add drag and drop functionality to UI
            %enable_drag_and_drop (app.DataSelectorUIFigure, @file_dropped);
            % call-back function to handel drag and drop event
            %function file_dropped (~, evt)
            %    parse_file_path ( app.CallingApp, evt.names{1} );
            %end
            
            % check data loaded, populate table
            if ( ~isprop(app.CallingApp, 'data') || isempty(app.CallingApp.data) )
                return;
            end

            data_to_UI(app, app.CallingApp.data);

            %data = app.CallingApp.data;
            %num_data = length(data);

            % in the future, keep appending new data to new row
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            % double click to show data property
            row = event.InteractionInformation.DisplayRow;
            col = event.InteractionInformation.DisplayColumn;
            % only respond to double click on data name column, and within
            % visible data rows
            if ( col~=2 || row==0 ) 
                return;
            end
            data = app.CallingApp.data{row};
            show_data_info(data);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % only check column "Acitve"
            if ( event.Indices(2) ~=1 )
                return;
            end
            row = event.Indices(1);
            idx = get_active_data_index (app.CallingApp);
            % make sure only 1 acitve row (data)
            if (row == idx)     % clicked on the already active data row, restore checked status, then do nothing
                app.UITable.Data{row, 1} = true;
            else    % switched active data to the selected row data
                app.UITable.Data{idx, 1} = false;   % uncheck previous active row
                set_active_data_index (app.CallingApp, row);
            end
        end

        % Menu selected function: propertiesMenu
        function propertiesMenuSelected(app, event)
            row = event.InteractionInformation.Row;
            if isempty( row )
                row = app.UITable.Selection;
            end
            data = app.CallingApp.data{row};
            show_data_info(data);
            %disp("prop row: "+event.Indices(1));
        end

        % Menu selected function: selectMenu
        function selectMenuSelected(app, event)
            row = event.InteractionInformation.Row;
            if isempty( row )
                return;
            end
            idx = get_active_data_index (app.CallingApp); % currently active data and row index
            % make sure only 1 acitve row (data)
            if (row ~= idx)     % switched active data to the selected row data
                app.UITable.Data{idx, 1} = false;   % uncheck previous active row
                app.UITable.Data{row, 1} = true;
                set_active_data_index (app.CallingApp, row);
            end
        end

        % Menu selected function: deleteMenu
        function deleteMenuSelected(app, event)
            row = event.InteractionInformation.Row;
            if isempty( row )
                return;
            end
            idx = get_active_data_index (app.CallingApp); % currently active data and row index
            if (row ~= idx)     % delete selected row in table, and selected data in app
                app.UITable.Data(row, :) = [];   % delete row in UI Table
                app.CallingApp.data(row) = [];
            else                % delete currently active data, put next data to active
                % check if it's the only data entry right now
                app.UITable.Data(row, :) = [];   % delete row in UI Table
                app.CallingApp.data(row) = [];
                if numel(app.CallingApp.data) ~= 0
                    app.UITable.Data{1, 1} = true;
                    set_active_data_index (app.CallingApp, 1);
                end
            end
        end

        % Callback function: DataSelectorUIFigure, DataSelectorUIFigure
        function UIFigureCloseRequest(app, event)
            close_child_app ( app );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataSelectorUIFigure = uifigure('Visible', 'off');
            app.DataSelectorUIFigure.Name = 'Data Selector';
            app.DataSelectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataSelectorUIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.DataSelectorUIFigure);
            app.UITable.ColumnName = {'Active'; 'Name'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UITable.Multiselect = 'off';
            app.ContextMenu = uicontextmenu(app.DataSelectorUIFigure);
            app.propertiesMenu = uimenu(app.ContextMenu);
            app.propertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @propertiesMenuSelected, true);
            app.propertiesMenu.Text = 'properties';
            app.selectMenu = uimenu(app.ContextMenu);
            app.selectMenu.MenuSelectedFcn = createCallbackFcn(app, @selectMenuSelected, true);
            app.selectMenu.Text = 'select';
            app.deleteMenu = uimenu(app.ContextMenu);
            app.deleteMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteMenuSelected, true);
            app.deleteMenu.Text = 'delete';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.DataSelectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_info(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataSelectorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DataSelectorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataSelectorUIFigure)
        end
    end
end