classdef dialog_preference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreferencesUIFigure             matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        FileTab                         matlab.ui.container.Tab
        currentdatafolderasthedefaultfolderCheckBox  matlab.ui.control.CheckBox
        FilesinhistorySpinner           matlab.ui.control.Spinner
        FilesinhistorySpinnerLabel      matlab.ui.control.Label
        DataTab                         matlab.ui.container.Tab
        Label_nm                        matlab.ui.control.Label
        localdensityRadiusEditField     matlab.ui.control.NumericEditField
        localdensitywithinradiusCheckBox  matlab.ui.control.CheckBox
        allDCRiterationforchannelseparationCheckBox  matlab.ui.control.CheckBox
        LoadIterDropDown                matlab.ui.control.DropDown
        LoadDropDownLabel               matlab.ui.control.Label
        effectiveCFRandEFCiterationCheckBox  matlab.ui.control.CheckBox
        datainfobyDataSelectorCheckBox  matlab.ui.control.CheckBox
        LocalizationPrecisionCheckBox   matlab.ui.control.CheckBox
        RIMFvalueZscalingfactorCheckBox  matlab.ui.control.CheckBox
        Label_compute                   matlab.ui.control.Label
        Label_show                      matlab.ui.control.Label
        Label_iteration                 matlab.ui.control.Label
        scatterplotCheckBox             matlab.ui.control.CheckBox
        Label_when_opening_data_file    matlab.ui.control.Label
        renderviewCheckBox              matlab.ui.control.CheckBox
        histogramplotCheckBox           matlab.ui.control.CheckBox
        attributeplotCheckBox           matlab.ui.control.CheckBox
        PlotTab                         matlab.ui.container.Tab
        renderpixelsizeSpinner          matlab.ui.control.Spinner
        renderpixelsizeSpinnerLabel     matlab.ui.control.Label
        RIMFSpinner                     matlab.ui.control.Spinner
        RIMFSpinnerLabel                matlab.ui.control.Label
        rendercolormapDropDown          matlab.ui.control.DropDown
        rendercolormapDropDown_2Label   matlab.ui.control.Label
        PlotcolormapDropDown            matlab.ui.control.DropDown
        PlotcolormapDropDown_2Label     matlab.ui.control.Label
        ROIcolorDropDown                matlab.ui.control.DropDown
        ROIcolorDropDown_2Label         matlab.ui.control.Label
        Label_nm_2                      matlab.ui.control.Label
        Label_default_parameters        matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        CallingApp                              % MINFLUX Viewer main app
        Name = "dialog_preference";
        Tag = 'dialog_preference'; % Description % Description
    end
    
    methods (Access = private)
        
        % function set_default_prefs (app)
        %     file = struct("num_file_history", 5);
        % 
        %     data = struct();
        %     data.iter_load = "last";
        %     data.load_efc_cfr = true;
        %     data.load_all_dcr = true;
        %     data.compute_rimf = false;
        %     data.compute_locPrec = false;
        %     data.compute_local_density = false;
        %     data.local_density_radius = 100;
        %     data.show_data_info = true;
        %     data.shw_attr_plot = true;
        %     data.show_scatter = false;
        %     data.show_histogram = false;
        %     data.show_render = false;
        % 
        %     plot = struct();
        %     plot.rimf_value = 0.67;
        %     plot.render_pixel_size = 2;
        %     plot.render_cmap = "Hot";
        %     plot.plot_cmap = "single color";
        %     plot.roi_color = "Yellow";
        % 
        %     app.Prefs_default = struct("file", file, "data", data, "plot", plot);
        % end

        function set_dialog_value (app, Prefs)
            file = Prefs.file;
            data = Prefs.data;
            plot = Prefs.plot;

            app.FilesinhistorySpinner.Value = file.num_file_history;
            app.currentdatafolderasthedefaultfolderCheckBox.Value = file.keep_last_folder;

            app.LoadIterDropDown.Value = data.iter_load;
            app.effectiveCFRandEFCiterationCheckBox.Value = data.load_efc_cfr;
            app.allDCRiterationforchannelseparationCheckBox.Value = data.load_all_dcr;

            app.RIMFvalueZscalingfactorCheckBox.Value = data.compute_rimf;
            app.LocalizationPrecisionCheckBox.Value = data.compute_locPrec;
            app.localdensitywithinradiusCheckBox.Value = data.compute_local_density;
            app.localdensityRadiusEditField.Value = data.local_density_radius;

            app.datainfobyDataSelectorCheckBox.Value = data.show_data_info;
            app.attributeplotCheckBox.Value = data.shw_attr_plot;
            app.scatterplotCheckBox.Value = data.show_scatter;
            app.histogramplotCheckBox.Value = data.show_histogram;
            app.renderviewCheckBox.Value = data.show_render;
            
            app.RIMFSpinner.Value =plot.rimf_value;
            app.renderpixelsizeSpinner.Value = plot.render_pixel_size;
            app.rendercolormapDropDown.Value = plot.render_cmap;
            app.PlotcolormapDropDown.Value = plot.plot_cmap;
            app.ROIcolorDropDown.Value = plot.roi_color;
        end


        function Prefs = get_dialog_value (app)
            file = struct();
            file.num_file_history = app.FilesinhistorySpinner.Value;
            file.keep_last_folder = app.currentdatafolderasthedefaultfolderCheckBox.Value;

            data = struct();
            data.iter_load = app.LoadIterDropDown.Value;
            data.load_efc_cfr = app.effectiveCFRandEFCiterationCheckBox.Value;
            data.load_all_dcr = app.allDCRiterationforchannelseparationCheckBox.Value;

            data.compute_rimf = app.RIMFvalueZscalingfactorCheckBox.Value;
            data.compute_locPrec = app.LocalizationPrecisionCheckBox.Value;
            data.compute_local_density = app.localdensitywithinradiusCheckBox.Value;
            data.local_density_radius = app.localdensityRadiusEditField.Value;

            data.show_data_info = app.datainfobyDataSelectorCheckBox.Value;
            data.shw_attr_plot = app.attributeplotCheckBox.Value;
            data.show_scatter = app.scatterplotCheckBox.Value;
            data.show_histogram = app.histogramplotCheckBox.Value;
            data.show_render = app.renderviewCheckBox.Value;
            
            plot = struct();
            plot.rimf_value = app.RIMFSpinner.Value;
            plot.render_pixel_size = app.renderpixelsizeSpinner.Value;
            plot.render_cmap = app.rendercolormapDropDown.Value;
            plot.plot_cmap = app.PlotcolormapDropDown.Value;
            plot.roi_color = app.ROIcolorDropDown.Value;

            Prefs = struct("file", file, "data", data, "plot", plot);
        end
        

        function reset_dialog_value ( app )
            app.FilesinhistorySpinner.Value = 5;
            app.currentdatafolderasthedefaultfolderCheckBox.Value = true;

            app.LoadIterDropDown.Value = "last";
            app.effectiveCFRandEFCiterationCheckBox.Value = true;
            app.allDCRiterationforchannelseparationCheckBox.Value = true;

            app.RIMFvalueZscalingfactorCheckBox.Value = false;
            app.LocalizationPrecisionCheckBox.Value = false;
            app.localdensitywithinradiusCheckBox.Value = false;
            app.localdensityRadiusEditField.Value = 100;

            app.datainfobyDataSelectorCheckBox.Value = false;
            app.attributeplotCheckBox.Value = true;
            app.scatterplotCheckBox.Value = false;
            app.histogramplotCheckBox.Value = false;
            app.renderviewCheckBox.Value = false;
            
            app.RIMFSpinner.Value = 0.67;
            app.renderpixelsizeSpinner.Value = 2;
            app.rendercolormapDropDown.Value = "Hot";
            app.PlotcolormapDropDown.Value = "single color";
            app.ROIcolorDropDown.Value = "Yellow";
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            %app.Prefs = load_prefs ();
            
            % Update UI with input values
            % app.LoadIterDropDown.Value = app.Prefs.on_data_load.iter;
            % app.attributeplotCheckBox.Value = app.Prefs.on_data_load.plot_attr;
            % app.scatterplotCheckBox.Value = app.Prefs.on_data_load.plot_scatter;
            % app.histogramplotCheckBox.Value = app.Prefs.on_data_load.plot_hist;
            % app.renderviewCheckBox.Value = app.Prefs.on_data_load.render_image;
            
            %app.RimfEditField.Value = app.Prefs.RIMF;
            if ~isprop(app.CallingApp, "Prefs")
                reset_dialog_value ( app );
            else
                set_dialog_value (app, app.CallingApp.Prefs);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            reset_dialog_value ( app );
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close_child_app (app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Prefs_current = get_dialog_value (app);
            % function to update but not delete existing app.Prefs
            app.CallingApp.Prefs = update_struct( app.CallingApp.Prefs, Prefs_current );
            setpref('mfx_viewer', 'Prefs', app.CallingApp.Prefs);       
            close_child_app (app);
        end

        % Close request function: PreferencesUIFigure
        function PreferencesUIFigureCloseRequest(app, event)
            close_child_app (app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreferencesUIFigure = uifigure('Visible', 'off');
            app.PreferencesUIFigure.Name = 'Preferences';
            app.PreferencesUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreferencesUIFigureCloseRequest, true);
            app.OKButton = uibutton(app.PreferencesUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TabGroup = uitabgroup(app.PreferencesUIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.FilesinhistorySpinnerLabel = uilabel(app.FileTab);
            app.FilesinhistorySpinnerLabel.Text = 'Files in history';
            app.FilesinhistorySpinner = uispinner(app.FileTab);
            app.FilesinhistorySpinner.Limits = [0 20];
            app.FilesinhistorySpinner.Value = 5;
            app.currentdatafolderasthedefaultfolderCheckBox = uicheckbox(app.FileTab);
            app.currentdatafolderasthedefaultfolderCheckBox.Text = 'current data folder as the default folder';
            app.currentdatafolderasthedefaultfolderCheckBox.Value = true;
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.attributeplotCheckBox = uicheckbox(app.DataTab);
            app.attributeplotCheckBox.Text = 'attribute plot';
            app.attributeplotCheckBox.Value = true;
            app.histogramplotCheckBox = uicheckbox(app.DataTab);
            app.histogramplotCheckBox.Text = 'histogram plot';
            app.renderviewCheckBox = uicheckbox(app.DataTab);
            app.renderviewCheckBox.Text = 'render view ';
            app.Label_when_opening_data_file = uilabel(app.DataTab);
            app.Label_when_opening_data_file.Text = 'When opening data file: ';
            app.scatterplotCheckBox = uicheckbox(app.DataTab);
            app.scatterplotCheckBox.Text = 'scatter plot';
            app.Label_iteration = uilabel(app.DataTab);
            app.Label_iteration.Text = 'iteration';
            app.Label_show = uilabel(app.DataTab);
            app.Label_show.Text = 'Show:';
            app.Label_compute = uilabel(app.DataTab);
            app.Label_compute.Text = 'Compute:';
            app.RIMFvalueZscalingfactorCheckBox = uicheckbox(app.DataTab);
            app.RIMFvalueZscalingfactorCheckBox.Text = 'RIMF value (Z scaling factor)';
            app.LocalizationPrecisionCheckBox = uicheckbox(app.DataTab);
            app.LocalizationPrecisionCheckBox.Text = 'Localization Precision';
            app.datainfobyDataSelectorCheckBox = uicheckbox(app.DataTab);
            app.datainfobyDataSelectorCheckBox.Text = 'data info... (by Data Selector)';
            app.datainfobyDataSelectorCheckBox.Value = true;
            app.effectiveCFRandEFCiterationCheckBox = uicheckbox(app.DataTab);
            app.effectiveCFRandEFCiterationCheckBox.Text = 'effective CFR and EFC iteration';
            app.effectiveCFRandEFCiterationCheckBox.Value = true;
            app.LoadDropDownLabel = uilabel(app.DataTab);
            app.LoadDropDownLabel.Text = 'Load';
            app.LoadIterDropDown = uidropdown(app.DataTab);
            app.LoadIterDropDown.Items = {'last', 'all'};
            app.LoadIterDropDown.Value = 'last';
            app.allDCRiterationforchannelseparationCheckBox = uicheckbox(app.DataTab);
            app.allDCRiterationforchannelseparationCheckBox.Text = 'all DCR iteration for channel separation';
            app.localdensitywithinradiusCheckBox = uicheckbox(app.DataTab);
            app.localdensitywithinradiusCheckBox.Text = 'local density within radius';
            app.localdensityRadiusEditField = uieditfield(app.DataTab, 'numeric');
            app.localdensityRadiusEditField.Limits = [0 Inf];
            app.localdensityRadiusEditField.Value = 100;
            app.Label_nm = uilabel(app.DataTab);
            app.Label_nm.Text = 'nm';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.Label_default_parameters = uilabel(app.PlotTab);
            app.Label_default_parameters.Text = 'Default parameters;';
            app.Label_nm_2 = uilabel(app.PlotTab);
            app.Label_nm_2.Text = 'nm';
            app.ROIcolorDropDown_2Label = uilabel(app.PlotTab);
            app.ROIcolorDropDown_2Label.Text = 'ROI color';
            app.ROIcolorDropDown = uidropdown(app.PlotTab);
            app.ROIcolorDropDown.Items = {'Yellow', 'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Custom'};
            app.ROIcolorDropDown.Value = 'Yellow';
            app.PlotcolormapDropDown_2Label = uilabel(app.PlotTab);
            app.PlotcolormapDropDown_2Label.Text = 'Plot colormap';
            app.PlotcolormapDropDown = uidropdown(app.PlotTab);
            app.PlotcolormapDropDown.Items = {'single color', 'Parula', 'Jet', 'Glasbey'};
            app.PlotcolormapDropDown.Value = 'single color';
            app.rendercolormapDropDown_2Label = uilabel(app.PlotTab);
            app.rendercolormapDropDown_2Label.Text = 'render colormap';
            app.rendercolormapDropDown = uidropdown(app.PlotTab);
            app.rendercolormapDropDown.Items = {'Hot', 'Parula', 'Jet', 'Glasbey'};
            app.rendercolormapDropDown.Value = 'Hot';
            app.RIMFSpinnerLabel = uilabel(app.PlotTab);
            app.RIMFSpinnerLabel.Text = 'RIMF';
            app.RIMFSpinner = uispinner(app.PlotTab);
            app.RIMFSpinner.Step = 0.001;
            app.RIMFSpinner.Limits = [0 1];
            app.RIMFSpinner.Value = 0.67;
            app.renderpixelsizeSpinnerLabel = uilabel(app.PlotTab);
            app.renderpixelsizeSpinnerLabel.Text = 'render pixel size';
            app.renderpixelsizeSpinner = uispinner(app.PlotTab);
            app.renderpixelsizeSpinner.Limits = [0 Inf];
            app.renderpixelsizeSpinner.Value = 2;
            app.ResetButton = uibutton(app.PreferencesUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CancelButton = uibutton(app.PreferencesUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.PreferencesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_preference(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreferencesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreferencesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreferencesUIFigure)
        end
    end
end